classdef Tugas1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        TabGroup                   matlab.ui.container.TabGroup
        Tab                        matlab.ui.container.Tab
        HistogramEqualizationTab   matlab.ui.container.Tab
        LoadImageButton            matlab.ui.control.Button
        ExampleImageHisteq         matlab.ui.control.DropDown
        ExampleImageDropDownLabel  matlab.ui.control.Label
        InputLabel                 matlab.ui.control.Label
        ResultLabel                matlab.ui.control.Label
        BlueAxesInput_2            matlab.ui.control.UIAxes
        RedAxesInput_2             matlab.ui.control.UIAxes
        GreenAxesInput_2           matlab.ui.control.UIAxes
        ImageInput_2               matlab.ui.control.UIAxes
        BlueAxesInput              matlab.ui.control.UIAxes
        RedAxesInput               matlab.ui.control.UIAxes
        GreenAxesInput             matlab.ui.control.UIAxes
        ImageInput                 matlab.ui.control.UIAxes
        HistogramSpecificationTab  matlab.ui.container.Tab
    end

    % Methid for image histogram equalization
    methods (Access = private)
        % Histogram Equalization function helper
        function loadImageHisteq(app, imagefile)
            try
                im = imread(imagefile);
            catch ME
                % If problem reading image, display error message
                uialert(app.UIFigure, ME.message, 'Image Error');
                return;
            end

            updateInputHisteq(app, im); 
            switch size(im,3)
                case 1
                    imHisteq = histeq(app,im);
                case 3
                    imHisteq(:,:,1) = histeq(app,im(:,:,1));
                    imHisteq(:,:,2) = histeq(app,im(:,:,2));
                    imHisteq(:,:,3) = histeq(app,im(:,:,3));
                otherwise
                    % Error when image is not grayscale or truecolor
                    uialert(app.UIFigure, 'Image must be grayscale or truecolor.', 'Image Error');
                    return;
            end
            disp(size(im))
            disp(size(imHisteq))
            updateOutputHisteq(app,imHisteq)
        end

        function updateInputHisteq(app, im)
            switch size(im,3)
                case 1
                    % Display the grayscale image
                    imagesc(app.ImageInput,im);
                    
                    % Plot all histograms with the same data for grayscale
                    histr = histogram(app.RedAxesInput, im, 'FaceColor',[1 0 0],'EdgeColor', 'none');
                    histg = histogram(app.GreenAxesInput, im, 'FaceColor',[0 1 0],'EdgeColor', 'none');
                    histb = histogram(app.BlueAxesInput, im, 'FaceColor',[0 0 1],'EdgeColor', 'none');
                    
                case 3
                    % Display the truecolor image
                    imagesc(app.ImageInput,im);
                    
                    % Plot the histograms
                    histr = histogram(app.RedAxesInput, im(:,:,1), 'FaceColor', [1 0 0], 'EdgeColor', 'none');
                    histg = histogram(app.GreenAxesInput, im(:,:,2), 'FaceColor', [0 1 0], 'EdgeColor', 'none');
                    histb = histogram(app.BlueAxesInput, im(:,:,3), 'FaceColor', [0 0 1], 'EdgeColor', 'none');
                    
                otherwise
                    % Error when image is not grayscale or truecolor
                    uialert(app.UIFigure, 'Image must be grayscale or truecolor.', 'Image Error');
                    return;
            end
                % Get largest bin count
                maxr = max(histr.BinCounts);
                maxg = max(histg.BinCounts);
                maxb = max(histb.BinCounts);
                maxcount = max([maxr maxg maxb]);
                
                % Set y axes limits based on largest bin count
                app.RedAxesInput.YLim = [0 maxcount];
                app.RedAxesInput.YTick = round([0 maxcount/2 maxcount], 2, 'significant');
                app.GreenAxesInput.YLim = [0 maxcount];
                app.GreenAxesInput.YTick = round([0 maxcount/2 maxcount], 2, 'significant');
                app.BlueAxesInput.YLim = [0 maxcount];
                app.BlueAxesInput.YTick = round([0 maxcount/2 maxcount], 2, 'significant');
        end

        function updateOutputHisteq(app, im)
            switch size(im,3)
                case 1
                    % Display the grayscale image
                    imagesc(app.ImageInput_2,im);
                    
                    % Plot all histograms with the same data for grayscale
                    histr = histogram(app.RedAxesInput_2, im, 'FaceColor',[1 0 0],'EdgeColor', 'none');
                    histg = histogram(app.GreenAxesInput_2, im, 'FaceColor',[0 1 0],'EdgeColor', 'none');
                    histb = histogram(app.BlueAxesInput_2, im, 'FaceColor',[0 0 1],'EdgeColor', 'none');
                    
                case 3
                    % Display the truecolor image
                    imagesc(app.ImageInput_2,im);
                    
                    % Plot the histograms
                    histr = histogram(app.RedAxesInput_2, im(:,:,1), 'FaceColor', [1 0 0], 'EdgeColor', 'none');
                    histg = histogram(app.GreenAxesInput_2, im(:,:,2), 'FaceColor', [0 1 0], 'EdgeColor', 'none');
                    histb = histogram(app.BlueAxesInput_2, im(:,:,3), 'FaceColor', [0 0 1], 'EdgeColor', 'none');
                    
                otherwise
                    % Error when image is not grayscale or truecolor
                    uialert(app.UIFigure, 'Image must be grayscale or truecolor.', 'Image Error');
                    return;
            end
                % Get largest bin count
                maxr = max(histr.BinCounts);
                maxg = max(histg.BinCounts);
                maxb = max(histb.BinCounts);
                maxcount = max([maxr maxg maxb]);
                
                % Set y axes limits based on largest bin count
                app.RedAxesInput_2.YLim = [0 maxcount];
                app.RedAxesInput_2.YTick = round([0 maxcount/2 maxcount], 2, 'significant');
                app.GreenAxesInput_2.YLim = [0 maxcount];
                app.GreenAxesInput_2.YTick = round([0 maxcount/2 maxcount], 2, 'significant');
                app.BlueAxesInput_2.YLim = [0 maxcount];
                app.BlueAxesInput_2.YTick = round([0 maxcount/2 maxcount], 2, 'significant');
        end

        function imResult = histeq(~, im)
            numofpixels = size(im,1)*size(im,2);
            imResult = uint8(zeros(size(im)));
            histEq = zeros(256);
            histFreq = zeros(256);
            histProb = zeros(256);
            
            for i = 1:size(im,1)
                for j = 1:size(im,2)
                    greyLevel = im(i,j);
                    histFreq(greyLevel+1)=histFreq(greyLevel+1)+1;
                    histProb(greyLevel+1)=histFreq(greyLevel+1)/numofpixels;
                end
            end

            sum = 0.0;
            for i = 1:256
                sum = sum + histFreq(i);
                histEq(i) = floor((sum/numofpixels)*255);
            end

            disp(histEq)

            imSize = size(im);
            for i = 1:imSize(1)
                for j = 1:imSize(2)
                    imResult(i,j) = histEq(im(i,j)+1);
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ImageInput.Visible = 'off';
            app.ImageInput.Colormap = gray(256);
            axis(app.ImageInput, 'image');

            app.ImageInput_2.Visible = 'off';
            app.ImageInput_2.Colormap = gray(256);
            axis(app.ImageInput_2, 'image');

            loadImageHisteq(app, 'citra kontras rendah/Picture1.jpg')
        end

        % Value changed function: ExampleImageHisteq
        function ExampleImageHisteqValueChanged(app, event)
            value = app.ExampleImageHisteq.Value;
            switch value
                case 'Kacang'
                    loadImageHisteq(app, 'citra kontras rendah/Picture1.jpg')
                case 'Kota'
                    loadImageHisteq(app, 'citra kontras rendah/Picture2.jpg')
                case 'Jembatan'
                    loadImageHisteq(app, 'citra kontras rendah/Picture3.png')
                case 'Ruangan'
                    loadImageHisteq(app, 'citra kontras rendah/Picture4.jpg')
                case 'Pacar Rahadian'
                    loadImageHisteq(app, 'citra kontras rendah/Picture5.jpg')
                case 'Kucing'
                    loadImageHisteq(app, 'citra kontras rendah/Picture6.jpg')
            end
        end

        % Button pushed function: LoadImageButton
        function LoadImageHisteqButtonPushed(app, event)
            % Display uigetfile dialog
            filterspec = {'*.jpg;*.tif;*.png;*.gif','All Image Files'};
            [f, p] = uigetfile(filterspec);
            
            % Make sure user didn't cancel uigetfile dialog
            if (ischar(p))
               fname = [p f];
               loadImageHisteq(app, fname);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.HistogramEqualizationTab = uitab(app.TabGroup);
            app.HistogramEqualizationTab.Title = 'Histogram Equalization';
            app.ImageInput = uiaxes(app.HistogramEqualizationTab);
            app.ImageInput.XLim = [0 1];
            app.ImageInput.XTick = [];
            app.ImageInput.YTick = [];
            app.GreenAxesInput = uiaxes(app.HistogramEqualizationTab);
            title(app.GreenAxesInput, 'Green')
            xlabel(app.GreenAxesInput, 'Intensity')
            ylabel(app.GreenAxesInput, 'Pixels')
            app.GreenAxesInput.XLim = [0 255];
            app.GreenAxesInput.XTick = [0 128 255];
            app.RedAxesInput = uiaxes(app.HistogramEqualizationTab);
            title(app.RedAxesInput, 'Red')
            xlabel(app.RedAxesInput, 'Intensity')
            ylabel(app.RedAxesInput, 'Pixels')
            app.RedAxesInput.XLim = [0 255];
            app.RedAxesInput.XTick = [0 128 255];
            app.BlueAxesInput = uiaxes(app.HistogramEqualizationTab);
            title(app.BlueAxesInput, 'Blue')
            xlabel(app.BlueAxesInput, 'Intensity')
            ylabel(app.BlueAxesInput, 'Pixels')
            app.BlueAxesInput.XLim = [0 255];
            app.BlueAxesInput.XTick = [0 128 255];
            app.ImageInput_2 = uiaxes(app.HistogramEqualizationTab);
            app.ImageInput_2.XLim = [0 1];
            app.ImageInput_2.XTick = [];
            app.ImageInput_2.YTick = [];
            app.GreenAxesInput_2 = uiaxes(app.HistogramEqualizationTab);
            title(app.GreenAxesInput_2, 'Green')
            xlabel(app.GreenAxesInput_2, 'Intensity')
            ylabel(app.GreenAxesInput_2, 'Pixels')
            app.GreenAxesInput_2.XLim = [0 255];
            app.GreenAxesInput_2.XTick = [0 128 255];
            app.RedAxesInput_2 = uiaxes(app.HistogramEqualizationTab);
            title(app.RedAxesInput_2, 'Red')
            xlabel(app.RedAxesInput_2, 'Intensity')
            ylabel(app.RedAxesInput_2, 'Pixels')
            app.RedAxesInput_2.XLim = [0 255];
            app.RedAxesInput_2.XTick = [0 128 255];
            app.BlueAxesInput_2 = uiaxes(app.HistogramEqualizationTab);
            title(app.BlueAxesInput_2, 'Blue')
            xlabel(app.BlueAxesInput_2, 'Intensity')
            ylabel(app.BlueAxesInput_2, 'Pixels')
            app.BlueAxesInput_2.XLim = [0 255];
            app.BlueAxesInput_2.XTick = [0 128 255];
            app.ResultLabel = uilabel(app.HistogramEqualizationTab);
            app.ResultLabel.Text = 'Result';
            app.InputLabel = uilabel(app.HistogramEqualizationTab);
            app.InputLabel.Text = 'Input';
            app.ExampleImageDropDownLabel = uilabel(app.HistogramEqualizationTab);
            app.ExampleImageDropDownLabel.Text = 'Example Image';
            app.ExampleImageHisteq = uidropdown(app.HistogramEqualizationTab);
            app.ExampleImageHisteq.Items = {'Kacang', 'Kota', 'Jembatan', 'Ruangan', 'Pacar Rahadian', 'Kucing'};
            app.ExampleImageHisteq.ValueChangedFcn = createCallbackFcn(app, @ExampleImageHisteqValueChanged, true);
            app.ExampleImageHisteq.Value = 'Kacang';
            app.LoadImageButton = uibutton(app.HistogramEqualizationTab, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageHisteqButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.HistogramSpecificationTab = uitab(app.TabGroup);
            app.HistogramSpecificationTab.Title = 'Histogram Specification';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Tugas1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end